classdef DisplayRawData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        PlotButton      matlab.ui.control.Button
        NextButton      matlab.ui.control.Button
        PreviousButton  matlab.ui.control.Button
        EditField       matlab.ui.control.EditField
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes3         matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
        UIAxes4         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        errorMessage = [] % Description
        startTime = []; % First value of the graphs
        endTime = []; % Last value of the graphs
        Fs = 200; % Sampling frequency of OpenBCI GUI
        windowDuration = 10;
        totalDuration = [];
        numberOfChannels = [];
        eegDataStored = [];
    end
    
    methods (Access = private)
        
        function plotRawData(app)
            try
                % Getting from the global memory of matlab the eegData sampled
                % inside the principal application
                eegData    = getappdata(0, 'eegData');
                dimEegData = getappdata(0, 'dimEegData');
    
                % Enabling only the necessary graphs based on the number of
                % channels
                app.numberOfChannels = dimEegData(1);
                numberOfSamples = dimEegData(2);

                app.eegDataStored = eegData;
                app.totalDuration = numberOfSamples / app.Fs;

                if app.numberOfChannels >= 1 && app.numberOfChannels <= 4
                    switch app.numberOfChannels
                        case 1
                            app.UIAxes.Visible = 'on';
                        case 2
                            app.UIAxes.Visible = 'on';
                            app.UIAxes2.Visible = 'on';
                        case 3
                            app.UIAxes.Visible = 'on';
                            app.UIAxes2.Visible = 'on';
                            app.UIAxes3.Visible = 'on';
                        case 4
                            app.UIAxes.Visible = 'on';
                            app.UIAxes2.Visible = 'on';
                            app.UIAxes3.Visible = 'on';
                            app.UIAxes4.Visible = 'on';
                    end

                    app.startTime = 0;
                    app.endTime = min(app.windowDuration, app.totalDuration);

                    % Starting a new function to plot the current graph
                    plotCurrentWindow(app)

                    % Enabling Previous / Next Button
                    app.PreviousButton.Enable = 'on';
                    app.NextButton.Enable = 'on';
                else
                    app.errorMessage = 'The number of channels is not compatible. Please check the quality of the eeg dataset and try again';
                    uialert(app.UIFigure, app.errorMessage,'Error',Icon='error');
                    resetApp(app)
                end
            catch
                app.errorMessage = 'There was an erorr occuring the plotting session. Please check the quality of the eeg dataset and try again.';
                uialert(app.UIFigure,app.errorMessage,'Error',Icon='error')
                resetApp(app)
            end
        end
        
        function resetApp(app)
            % Function that reset the application in case of errors

            app.PreviousButton.Enable = 'off';
            app.NextButton.Enable = 'off';
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'off';
            app.UIAxes4.Visible = 'off';
            app.PlotButton.Enable = 'off';
            app.startTime = 0;
            app.endTime = [];
            app.eegDataStored = [];
            app.numberOfChannels = [];
            return
        end
        
        function plotCurrentWindow(app)
            startSample = max(1,round(app.startTime*app.Fs)+1);
            endSample = min(size(app.eegDataStored,2), round(app.endTime*app.Fs));

            timeVector = (startSample-1:endSample-1)/app.Fs;
            axesHandles = [app.UIAxes, app.UIAxes2, app.UIAxes3, app.UIAxes4];

            for currentChannel = 1:app.numberOfChannels
                currentVector = app.eegDataStored(currentChannel, startSample:endSample);
                currentAxes = axesHandles(currentChannel);

                plot(currentAxes, timeVector, currentVector);
                xlabel(currentAxes, 'Time (s)');
                ylabel(currentAxes, 'Amplitude (\muV)');
                title(currentAxes, sprintf('Channel %d', currentChannel));
                grid(currentAxes, 'on');
                xlim(currentAxes, [app.startTime, app.endTime]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Startup function that introduce the Display Raw Data app
            
            % Make the various element of the GUI not active
            app.PreviousButton.Enable = 'off';
            app.NextButton.Enable = 'off';
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'off';
            app.UIAxes4.Visible = 'off';
            app.PlotButton.Enable = 'on';


        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            % Callback to see previous parts of he graphs
            newStartTime = app.startTime - app.windowDuration;

            app.startTime = max(0, newStartTime);
            app.endTime = app.startTime + app.windowDuration;

            if app.startTime == 0
                app.PreviousButton.Enable = 'off';
            else
                app.PreviousButton.Enable = 'on';
            end

            if app.startTime == 0
                app.PreviousButton.Enable = 'off';
            else
                app.PreviousButton.Enable = 'on';
            end

            if app.endTime == app.totalDuration
                app.NextButton.Enable = 'off';
            else
                app.NextButton.Enable = 'on';
            end

            plotCurrentWindow(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Callback to see next parts of the graphs

            newStartTime = app.startTime+app.windowDuration;
            if newStartTime >= app.totalDuration
                newStartTime = app.startTime;
            end
            app.startTime = newStartTime;
            app.endTime = min(app.startTime + app.windowDuration, app.totalDuration);

            if app.startTime == 0
                app.PreviousButton.Enable = 'off';
            else
                app.PreviousButton.Enable = 'on';
            end

            if app.endTime == app.totalDuration
                app.NextButton.Enable = 'off';
            else
                app.NextButton.Enable = 'on';
            end

            plotCurrentWindow(app)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Callback that start the plotting session

            % Starting the plotting session
            plotRawData(app)

            % Make the button not available anymore after starting the
            % plotting session
            app.PlotButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Callback that close the Application

            app.PreviousButton.Enable = 'off';
            app.NextButton.Enable = 'off';
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'off';
            app.UIAxes4.Visible = 'off';
            app.PlotButton.Enable = 'off';
            app.startTime = 0;
            app.endTime = [];
            app.eegDataStored = [];
            app.numberOfChannels = [];

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Channel 4')
            xlabel(app.UIAxes4, 'Time (s)')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Channel 1')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Channel 3')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Channel 2')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'RAW DATA';
            app.PreviousButton = uibutton(app.GridLayout, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Tooltip = {''};
            app.PreviousButton.Text = '◀ Previous';
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next  ▶';
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplayRawData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end