classdef NIA_BCI_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TextArea                      matlab.ui.control.TextArea
        StopcurrentsessionButton      matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        StarttrainingsessionCheckBox  matlab.ui.control.CheckBox
        DataprocessingwithEEGLABCheckBox  matlab.ui.control.CheckBox
        DisplayrawdataCheckBox        matlab.ui.control.CheckBox
        EditField                     matlab.ui.control.EditField
        dEditField                    matlab.ui.control.EditField
        StartnewsessionButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        errorMessage = []; % Message displayed in case of errors
        eegData = []; % Data Matrix with raw data
        dimEegData = []; % Dimensions of the eedData Matrix
        subject = []; % Subject Code
        numberOfTrial = []; % Trial Number
        displayRawDataValue = []; % Check Box for Display Raw Data
        dataProcessingValue = []; % Chech Box for Data Processing
        startTrainingValue = []; % Check Box for Training Session
        displayRawDataAppHandle = []; % Handle for Display Raw Data App
        trainingSessionHandle = []; % Handle for Training Session App
    end
    
    methods (Access = private)
        
        function resetAppStateAfterError(app)
            app.errorMessage = []; 
            app.eegData = []; 
            app.dimEegData = []; 
            app.subject = []; 
            app.numberOfTrial = [];
            app.StartnewsessionButton.Enable = 'on';
            app.dEditField.Enable = 'off';
            app.dEditField.Editable = 'off';
            app.dEditField.Value = '';
            app.TextArea.Enable = 'off';
            app.TextArea.Editable = 'off';
            app.TextArea.Value = '';
            app.DisplayrawdataCheckBox.Enable = 'off';
            app.DisplayrawdataCheckBox.Value = 0;
            app.DataprocessingwithEEGLABCheckBox.Enable = 'off';
            app.DataprocessingwithEEGLABCheckBox.Value = 0;
            app.StarttrainingsessionCheckBox.Enable = 'off';
            app.StarttrainingsessionCheckBox.Value = 0;
            app.RunButton.Enable = 'off';
            app.StopcurrentsessionButton.Enable = 'off';
            
        end
        
        function DataProcessingWithEEGLABFunction(app)
            try
                % Verify whether EEGLAB has been already launched
                existingEEGLAB = findobj('Tag', 'EEGLAB');
                if ~isempty(existingEEGLAB)
                    app.errorMessage = 'The Data Processing Feature has been already launched';
                    uialert(app.UIFigure, app.errorMessage, 'Warning', Icon='warning')
                    return
                end
   
                try
                    % Saving eegData in the proper way for EEGLAB
                    assignin('base', 'eegData', app.eegData);
                catch ME
                    app.errorMessage = 'Something went wrong during the saving of eegData for EEGLAB. Please check the inner code or check the quality of eeg data.';
                    uialert(app.UIFigure, app.errorMessage, 'Warning', Icon='warning')
                    resetAppStateAfterError(app)
                    return
                end
                
                % Researching for the EEGLAB Folder 
                if isdeployed
                    eeglabRoot = fullfile(ctfroot, 'NIA_BCI_v1_P', 'eeglab2026.0.0');
                else
                    appFolder = fileparts(mfilename('fullpath'));
                    eeglabRoot = fullfile(appFolder, 'eeglab2026.0.0');
                end
    
                if ~exist(eeglabRoot, 'dir')
                    app.errorMessage = 'The EEGLAB folder has not been found';
                    uialert(app.UIFigure, app.errorMessage, 'Error', Icon='error');
                    return
                end
    
                % Researching for EEGLAB Specific Function
                if isempty(which('eeglab'))
                    addpath(genpath(eeglabRoot));
                end
    
                % Launching EEGLAB
                try
                    eeglab;
                catch ME
                    app.errorMessage = 'EEGLAB has not been found, please try again.';
                    uialert(app.UIFigure, app.errorMessage, 'Error', Icon='error')
                    resetAppStateAfterError(app)
                end
            catch ME
                app.errorMessage = 'There is an error occuring the Processing phase. Please check the quality of EEGLAB folders.';
                uialert(app.UIFigure, app.errorMessage, 'Error', Icon='error')
                resetAppStateAfterError(app)
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Function that makes all the elements of the GUI non available
            % in order to make only Start new session button enabled

            app.StartnewsessionButton.Enable = 'on';
            app.dEditField.Enable = 'off';
            app.dEditField.Editable = 'off';
            app.dEditField.Value = '';
            app.TextArea.Enable = 'off';
            app.TextArea.Editable = 'off';
            app.TextArea.Value = '';
            app.DisplayrawdataCheckBox.Enable = 'off';
            app.DisplayrawdataCheckBox.Value = 0;
            app.DataprocessingwithEEGLABCheckBox.Enable = 'off';
            app.DataprocessingwithEEGLABCheckBox.Value = 0;
            app.StarttrainingsessionCheckBox.Enable = 'off';
            app.StarttrainingsessionCheckBox.Value = 0;
            app.RunButton.Enable = 'off';
            app.StopcurrentsessionButton.Enable = 'off';
            
        end

        % Button pushed function: StartnewsessionButton
        function StartnewsessionButtonPushed(app, event)
            % Callback that controls the "Start new session" button
            % This callback starts the proccesing phase, enabling the user
            % to select the desired data

            % Import Data from the desired Trial
            try
                % Opening of the .txt file that contains the RAW data recorded with
                % OpenBCI GUI
                [fileName, fileDirectory] = uigetfile({'*.txt','Text Files (*.txt)'; '*.*','All Files (*.*)'});
                if isempty(fileName) || isempty(fileDirectory)
                    app.errorMessage = 'You did not select any file. Please try again';
                    uialert(app.UIFigure, app.errorMessage,'Error', Icon='error')
                    resetAppStateAfterError(app)
                    return
                end
                
                % Check of the file format
                if ~strcmp(fileName(end-3:end), '.txt')
                    app.errorMessage = 'The selected file is not a .txt file, Please Check the format of the desired file';
                    uialert(app.UIFigure,app.errorMessage,'Error',Icon='error');
                    resetAppStateAfterError(app)
                    return
                end
                
                % Saving the Directory of the .txt file
                try
                    directoryElements = strsplit(string(fileDirectory),'\');
                    currentAnalysedTrial = directoryElements{end-1};
                    analisedTrialElements = strsplit(currentAnalysedTrial,'_');
                catch ME
                    app.errorMessage = 'There is an error occuring the format of the selected Directory';
                    uialert(app.UIFigure, app.errorMessage, 'Error', Icon='error');
                    resetAppStateAfterError(app)
                    return
                end
            
                % Check the Nature of the Trial
                if ~strcmp(analisedTrialElements{1},'EEG')
                    app.errorMessage = 'The selected file is not related to an EEG measurement. Please check the type of the desired trial';
                    uialert(app.UIFigure,app.errorMessage,'Wrong Trial')
                    resetAppStateAfterError(app)
                    return
                end

                try
                    % Saving the subject code and general info
                    app.subject = analisedTrialElements{2};
                    app.numberOfTrial = analisedTrialElements{3};
                catch ME
                    app.errorMessage = 'There is an error occuring the saving of the subject code and the general info. Please check the related part of the inner code';
                    uialert(app.UIFigure, app.errorMessage, 'Error', Icon='error')
                    resetAppStateAfterError(app)
                    return
                end
            
                % Creation of variables that contain the table values
                try
                    dataMatrix = readmatrix(fullfile(fileDirectory, fileName));
                catch ME
                    app.errorMessage = 'The table regarding trial info has not been found.';
                    uialert(app.UIFigure, app.errorMessage, 'Error',Icon='error');
                    resetAppStateAfterError(app)
                    return
                end
            
                % Reading the .xlsx file that contains the info about the specifc Trial
                infoFileName = strcat(app.subject,'_',app.numberOfTrial,'_InfoTrial.xlsx');
                infoFilePath = fullfile(fileDirectory, infoFileName);
                if ~isfile(infoFilePath)
                    app.errorMessage = 'The .xlsx info file was not found in the selected directory. Please check the file name and location Missing Info File';
                    uialert(app.UIFigure, app.errorMessage, 'Error',Icon='error');
                    resetAppStateAfterError(app)
                    return
                end
                infoTable = readtable(infoFilePath, 'ReadVariableNames', false);
                infoMatrix = readcell(infoFilePath);
                dimInfoMatrix = size(infoMatrix);
            
                % Reading the content of the .xlsx file and saving the info for the
                % next processing process
                channelVector = zeros(dimInfoMatrix(1),1);
                for rowInfoMatrix = 1:dimInfoMatrix(1)
                    if strcmp(infoTable{rowInfoMatrix, 2},'ON')
                        channelVector(rowInfoMatrix) = 1;
                    end
                end
            
                % Extracting the valid measurement vector from the data table based on
                % the active channels
                validDataMatrix = [];
                for channel = 1:length(channelVector)
                    if channelVector(channel) == 1
                        currentValidVector = dataMatrix(:,channel+1);
                        validDataMatrix = [validDataMatrix, currentValidVector];
                    end
                end
            
                % Saving the valid data in a dedicated matrix that will be used in
                % EEGLAB
                app.eegData = validDataMatrix';
                app.dimEegData = size(app.eegData);

                try
                    % Saving eegData into the global memory of matlab
                    setappdata(0, 'eegData', app.eegData)
                    setappdata(0, 'dimEegData', app.dimEegData)
                catch ME
                    app.errorMessage = 'There is an error occuring the saving of eegData into the global memory. Please chech the related part of the inner code.';
                    uialert(app.UIFigure, app.errorMessage, 'Error',Icon='error')
                    resetAppStateAfterError(app)
                    return
                end
            
            catch ME
                app.errorMessage = 'There is an error inside the code flow. Please check the workflow and the pipeline of the code'; 
                uialert(app.UIFigure, app.errorMessage,'Error',Icon='error')
                resetAppStateAfterError(app)
                return
            end

            % Make the start new session button not available anymore
            app.StartnewsessionButton.Enable = 'off';

            % Updating the text box with the subject code
            dEditFieldValueChanged(app)

            % Updating the text box with the general info about the Trial
            try
                subjectInfoFileName = strcat(app.subject, '_', 'InfoSubject.xlsx');
                subjectInfoFilePath = fullfile(fileDirectory, subjectInfoFileName);
            catch ME
                app.errorMessage = 'There is an error occuring the research of the .xlsx file related to Subject Info. Please check the quality of the inner code.';
                uialert(app.UIFigure, app.errorMessage, 'Error', Icon='error')
                resetAppStateAfterError(app)
                return
            end

            TextAreaValueChanged(app)

            if ~isfile(subjectInfoFilePath)
                app.errorMessage = 'The subject info .xlsx file was not found. Please check the file name and location';
                uialert(app.UIFigure, app.errorMessage, 'Error', Icon='error');
                resetAppStateAfterError(app)
                return
            end

            try
                subjectInfoTable = readtable(subjectInfoFilePath, 'ReadVariableNames', false);
                subjectInfoMatrix = readcell(subjectInfoFilePath);
                dimSubjectInfoMatrix = size(subjectInfoMatrix);

                setappdata(0,'subjectInfoMatrix',subjectInfoMatrix)
                
            catch ME
                app.errorMessage = 'The Table regarding the subject info has not been found.';
                uialert(app.UIFigure, app.errorMessage, 'Error', Icon='error')
                resetAppStateAfterError(app)
                return
            end

            try
                for rowSubjectInfoMatrix = 1:dimSubjectInfoMatrix(1)
                    if rowSubjectInfoMatrix == 1
                        currentParameter = string(subjectInfoTable{rowSubjectInfoMatrix,1});
                        currentParameterValue = lower(string(subjectInfoTable{rowSubjectInfoMatrix,2}));
                        newLine = sprintf('%s: %s', currentParameter, currentParameterValue);
                        app.TextArea.Value = {newLine};
                    else
                        currentParameter = string(subjectInfoTable{rowSubjectInfoMatrix,1});
                        currentParameterValue = string(subjectInfoTable{rowSubjectInfoMatrix,2});
                        switch currentParameter
                            case 'Pathology'
                                if strcmp(currentParameterValue, 'NO')
                                    currentParameterValue = 'no pathology to report';
                                end
                            case 'Physical Trauma'
                                if strcmp(currentParameterValue, 'NO')
                                    currentParameterValue = 'no physical trauma to report';
                                end
                            case 'Electrodes'
                                newLine = sprintf('%s: %s', currentParameter, currentParameterValue);
                                app.TextArea.Value = [app.TextArea.Value; {newLine}];
                                continue
                            case 'System Model'
                                newLine = sprintf('%s: %s', currentParameter, currentParameterValue);
                                app.TextArea.Value = [app.TextArea.Value; {newLine}];
                                continue
                        end
                        currentParameterValue = lower(currentParameterValue);
                        newLine = sprintf('%s: %s', currentParameter, currentParameterValue);
                        app.TextArea.Value = [app.TextArea.Value; {newLine}];
                    end
                end
            catch  ME
                app.errorMessage = 'There is an error in the internal structure of subject info file. Please check the quality of .xlsx files';
                uialert(app.UIFigure, app.errorMessage, 'Error', Icon='error')
                resetAppStateAfterError(app)
                return
            end

            % Make the Stop Session button available, in order to shutdown
            % the GUI
            app.StopcurrentsessionButton.Enable = 'on';
            
            % Make the various Check Boxes enabled
            app.DisplayrawdataCheckBox.Enable = 'on';
            app.DataprocessingwithEEGLABCheckBox.Enable = 'on';
            app.StarttrainingsessionCheckBox.Enable = 'on';

            % Make the Run button available
            app.RunButton.Enable = 'on';
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            % Callback that controls the displaying of the subject code
            % The text refers to the subject code of the selected Trial

            app.dEditField.Enable = 'on';
            app.dEditField.Value = sprintf('Subject: %s  /  Trial: %s', app.subject, app.numberOfTrial);
        end

        % Callback function
        function EditField_2ValueChanged(app, event)

        end

        % Button pushed function: StopcurrentsessionButton
        function StopcurrentsessionButtonPushed(app, event)
            % Button that stops the current session

            % Reset all app properties to initial state
            app.errorMessage = [];
            app.eegData = [];
            app.dimEegData = [];
            app.subject = [];
            app.numberOfTrial = [];

            % Make all the elements disabled in order to return to the
            % starting state
            app.dEditField.Enable = 'off';
            app.dEditField.Value = '';
            app.TextArea.Enable = 'off';
            app.TextArea.Value = '';
            app.DisplayrawdataCheckBox.Enable = 'off';
            app.DisplayrawdataCheckBox.Value = 0;
            app.DataprocessingwithEEGLABCheckBox.Enable = 'off';
            app.DataprocessingwithEEGLABCheckBox.Value = 0;
            app.StarttrainingsessionCheckBox.Enable = 'off';
            app.StarttrainingsessionCheckBox.Value = 0;
            app.RunButton.Enable = 'off';

            % Removing the created variables, in order to not sharing them
            % between each new session
            if isappdata(0, 'eegData')
                rmappdata(0, 'eegData');
            end
            if isappdata(0, 'dimEegData')
                rmappdata(0, 'dimEegData');
            end
            % Make the Start New Session button available
            app.StartnewsessionButton.Enable = 'on';
            app.StopcurrentsessionButton.Enable = 'off';
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            % Callback that displays the general info about the selected
            % trial
            app.TextArea.Enable = 'on';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Control of the possible action after importing the data
            % The possible actions are:
            % 1) Displaying the raw data
            % 2) Processing the data with EEGLAB
            % 3) Start a new training session

            if app.DisplayrawdataCheckBox.Value == 1
                if ~isempty(app.displayRawDataAppHandle) && isvalid(app.displayRawDataAppHandle)
                    app.errorMessage = 'Display Raw Data Feature has been already launched';
                    uialert(app.UIFigure, app.errorMessage,'Warning',Icon='warning')
                else
                    % Create a new instance and store its handle
                    app.displayRawDataAppHandle = DisplayRawData();
                end
            elseif app.DataprocessingwithEEGLABCheckBox.Value == 1
                % Create a new instance and store its handle
                DataProcessingWithEEGLABFunction(app)
            elseif app.StarttrainingsessionCheckBox.Value == 1
                if ~isempty(app.trainingSessionHandle) && isvalid(app.trainingSessionHandle)
                    app.errorMessage = 'Training Session Feature has been already launched';
                    uialert(app.UIFigure, app.errorMessage,'Warning',Icon='warning')
                else
                    % Create a new instance and store its handle
                    app.trainingSessionHandle = TrainingSession();
                end
            else
                app.errorMessage = 'You have to select one command from the available ones above the Run Button. Please try again';
                uialert(app.UIFigure,app.errorMessage,'Warning',Icon='warning')
            end
        end

        % Value changed function: DisplayrawdataCheckBox
        function DisplayrawdataCheckBoxValueChanged(app, event)
            app.displayRawDataValue = app.DisplayrawdataCheckBox.Value;
            if app.displayRawDataValue == 1
                app.DataprocessingwithEEGLABCheckBox.Value = 0;
                app.StarttrainingsessionCheckBox.Value = 0;
            end

        end

        % Value changed function: DataprocessingwithEEGLABCheckBox
        function DataprocessingwithEEGLABCheckBoxValueChanged(app, event)
            app.dataProcessingValue = app.DataprocessingwithEEGLABCheckBox.Value;
            if app.dataProcessingValue == 1
                app.DisplayrawdataCheckBox.Value = 0;
                app.StarttrainingsessionCheckBox.Value = 0;
            end
        end

        % Value changed function: StarttrainingsessionCheckBox
        function StarttrainingsessionCheckBoxValueChanged(app, event)
            app.startTrainingValue = app.StarttrainingsessionCheckBox.Value;
            if app.startTrainingValue == 1
                app.DataprocessingwithEEGLABCheckBox.Value = 0;
                app.DisplayrawdataCheckBox.Value = 0;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Button that closes the Interface

            % Reset all app properties to initial state
            app.errorMessage = [];
            app.eegData = [];
            app.dimEegData = [];
            app.subject = [];
            app.numberOfTrial = [];

            % Make all the elements disabled in order to return to the
            % starting state
            app.dEditField.Enable = 'off';
            app.dEditField.Value = '';
            app.TextArea.Enable = 'off';
            app.TextArea.Value = '';
            app.DisplayrawdataCheckBox.Enable = 'off';
            app.DisplayrawdataCheckBox.Value = 0;
            app.DataprocessingwithEEGLABCheckBox.Enable = 'off';
            app.DataprocessingwithEEGLABCheckBox.Value = 0;
            app.StarttrainingsessionCheckBox.Enable = 'off';
            app.StarttrainingsessionCheckBox.Value = 0;
            app.RunButton.Enable = 'off';

            % Removing the created variables, in order to not sharing them
            % between each new session
            if isappdata(0, 'eegData')
                rmappdata(0, 'eegData');
            end
            if isappdata(0, 'dimEegData')
                rmappdata(0, 'dimEegData');
            end
            % Make the Start New Session button available
            app.StartnewsessionButton.Enable = 'on';
            app.StopcurrentsessionButton.Enable = 'off';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartnewsessionButton = uibutton(app.UIFigure, 'push');
            app.StartnewsessionButton.ButtonPushedFcn = createCallbackFcn(app, @StartnewsessionButtonPushed, true);
            app.StartnewsessionButton.Text = 'Start new session';
            app.dEditField = uieditfield(app.UIFigure, 'text');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.dEditField.Editable = 'off';
            app.dEditField.Enable = 'off';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.DisplayrawdataCheckBox = uicheckbox(app.UIFigure);
            app.DisplayrawdataCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayrawdataCheckBoxValueChanged, true);
            app.DisplayrawdataCheckBox.Enable = 'off';
            app.DisplayrawdataCheckBox.Text = 'Display raw data';
            app.DataprocessingwithEEGLABCheckBox = uicheckbox(app.UIFigure);
            app.DataprocessingwithEEGLABCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataprocessingwithEEGLABCheckBoxValueChanged, true);
            app.DataprocessingwithEEGLABCheckBox.Enable = 'off';
            app.DataprocessingwithEEGLABCheckBox.Text = 'Data processing with EEGLAB';
            app.StarttrainingsessionCheckBox = uicheckbox(app.UIFigure);
            app.StarttrainingsessionCheckBox.ValueChangedFcn = createCallbackFcn(app, @StarttrainingsessionCheckBoxValueChanged, true);
            app.StarttrainingsessionCheckBox.Enable = 'off';
            app.StarttrainingsessionCheckBox.Text = 'Start training session';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.StopcurrentsessionButton = uibutton(app.UIFigure, 'push');
            app.StopcurrentsessionButton.ButtonPushedFcn = createCallbackFcn(app, @StopcurrentsessionButtonPushed, true);
            app.StopcurrentsessionButton.Enable = 'off';
            app.StopcurrentsessionButton.Text = 'Stop current session';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NIA_BCI_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end