classdef TrainingSession < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CentralTextArea             matlab.ui.control.TextArea
        LowerTextArea               matlab.ui.control.TextArea
        UpperTextArea               matlab.ui.control.TextArea
        RightTextArea               matlab.ui.control.TextArea
        LeftTextArea                matlab.ui.control.TextArea
        RightLamp                   matlab.ui.control.Lamp
        LeftLamp                    matlab.ui.control.Lamp
        LowerLamp                   matlab.ui.control.Lamp
        UpperLamp                   matlab.ui.control.Lamp
        TextArea                    matlab.ui.control.TextArea
        StartTrainingSessionButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        countdownValue = []; % Starting value for countdown
        countdownTimer = []; % Timer for countdown
        restTimer = []; % Timer for rest time
        restCountdownValue = []; % Countdown for rest session
        lampCountdownValue = []; % Countdown value
        lampTimer = []; % Duration for lamp loop
        caseOfLamp = []; % Type of activated lamp
        lampCounter = []; % Lamp counter for loop
        currentCase = []; % Current phase of training
        fSamplingSound = 44100; % Sampling Frequency for sound()
        soundDuration = 0.75; % Duration of the guiding sound
        soundFrequency = []; % Sound Frequency of the command
        soundTime = []; % Time vector for sound()
        soundFunction = []; % Periodic function for sound()
    end
    
    methods (Access = private)
        
        function updateCountdown(app)
            app.countdownValue = app.countdownValue - 1;
            if app.countdownValue > 0
                app.CentralTextArea.Value = sprintf('Start in %d', app.countdownValue);
            else
                app.CentralTextArea.Value = 'Go!';
                stop(app.countdownTimer);
                delete(app.countdownTimer);
            end
        end
        
        function updateRestCountdown(app)
            app.restCountdownValue = app.restCountdownValue - 1;
            if app.restCountdownValue > 0
                app.CentralTextArea.Value = sprintf('%d', app.restCountdownValue);
            else
                stop(app.restTimer);
                delete(app.restTimer);
            end
        end
        
        function updateLampCountdown(app)
            checkOpen = 0;
            if strcmp(app.currentCase,'open')
                checkOpen = 1;
            else
                checkOpen = 0;
            end
            for lampLoops = 1:2
                for numCurrentLamp = 1:4
                    currentLamp = app.caseOfLamp(numCurrentLamp);
                    switch currentLamp
                        case 'up'
                            app.UpperLamp.Color = [1.00,0.00,0.00];
                            app.RightLamp.Color = [0.65,0.65,0.65];
                            app.LowerLamp.Color = [0.65,0.65,0.65];
                            app.LeftLamp.Color = [0.65,0.65,0.65];
                            if checkOpen == 1
                                app.UpperTextArea.Enable = 'on';
                                app.UpperTextArea.Visible = 'on';
                                pause(5)
                                if ~isvalid(app); return; end
                                app.UpperTextArea.Visible = 'off';
                            else
                                % Sound Generation Setings
                                app.soundFrequency = 349;
                                app.soundTime = 0:1/app.fSamplingSound:app.soundDuration;
                                app.soundFunction = sin(2*pi*app.soundFrequency*app.soundTime); 
                                sound(app.soundFunction, app.fSamplingSound)
                                pause(5)
                                if ~isvalid(app); return; end
                            end
                        case 'right'
                            app.UpperLamp.Color = [0.65,0.65,0.65];
                            app.RightLamp.Color = [1.00,0.00,0.00];
                            app.LowerLamp.Color = [0.65,0.65,0.65];
                            app.LeftLamp.Color = [0.65,0.65,0.65];
                            if checkOpen == 1
                                app.RightTextArea.Enable = 'on';
                                app.RightTextArea.Visible = 'on';
                                pause(5)
                                if ~isvalid(app); return; end
                                app.RightTextArea.Visible = 'off';
                            else
                                % Sound Generation Setings
                                app.soundFrequency = 392;
                                app.soundTime = 0:1/app.fSamplingSound:app.soundDuration;
                                app.soundFunction = sin(2*pi*app.soundFrequency*app.soundTime);
                                sound(app.soundFunction, app.fSamplingSound)
                                pause(5)
                                if ~isvalid(app); return; end
                            end
                        case 'down'
                            app.UpperLamp.Color = [0.65,0.65,0.65];
                            app.RightLamp.Color = [0.65,0.65,0.65];
                            app.LowerLamp.Color = [1.00,0.00,0.00];
                            app.LeftLamp.Color = [0.65,0.65,0.65];
                            if checkOpen == 1
                                app.LowerTextArea.Enable = 'on';
                                app.LowerTextArea.Visible = 'on';
                                pause(5)
                                if ~isvalid(app); return; end
                                app.LowerTextArea.Visible = 'off';
                            else
                                % Sound Generation Setings
                                app.soundFrequency = 440;
                                app.soundTime = 0:1/app.fSamplingSound:app.soundDuration;
                                app.soundFunction = sin(2*pi*app.soundFrequency*app.soundTime);
                                sound(app.soundFunction, app.fSamplingSound)
                                pause(5)
                                if ~isvalid(app); return; end
                            end
                        case 'left'
                            app.UpperLamp.Color = [0.65,0.65,0.65];
                            app.RightLamp.Color = [0.65,0.65,0.65];
                            app.LowerLamp.Color = [0.65,0.65,0.65];
                            app.LeftLamp.Color = [1.00,0.00,0.00];
                            if checkOpen == 1
                                app.LeftTextArea.Enable = 'on';
                                app.LeftTextArea.Visible = 'on';
                                pause(5)
                                if ~isvalid(app); return; end
                                app.LeftTextArea.Visible = 'off';
                            else
                                % Sound Generation Setings
                                app.soundFrequency = 493.88;
                                app.soundTime = 0:1/app.fSamplingSound:app.soundDuration;
                                app.soundFunction = sin(2*pi*app.soundFrequency*app.soundTime);
                                sound(app.soundFunction, app.fSamplingSound)
                                pause(5)
                                if ~isvalid(app); return; end
                            end          
                    end
                end
                app.LeftLamp.Color = [0.65,0.65,0.65];
                app.CentralTextArea.Visible = 'on';
                app.CentralTextArea.Value = sprintf('Quick Rest');
                pause(10)
                if ~isvalid(app); return; end
                app.CentralTextArea.Visible = 'off';
            end
        end
        
        function resetApplication(app)
            app.StartTrainingSessionButton.Visible = 'on';
            app.StartTrainingSessionButton.Enable = 'on';
            app.UpperLamp.Color = [0.65,0.65,0.65];
            app.LowerLamp.Color = [0.65,0.65,0.65];
            app.LeftLamp.Color = [0.65,0.65,0.65];
            app.RightLamp.Color = [0.65,0.65,0.65];
            app.LeftTextArea.Visible = 'off';
            app.RightTextArea.Visible = 'off';
            app.UpperTextArea.Visible = 'off';
            app.LowerTextArea.Visible = 'off';
            app.CentralTextArea.Visible = 'off';
            app.TextArea.Enable = 'off';
            app.LeftTextArea.Enable = 'on';
            app.RightTextArea.Enable = 'on';
            app.UpperTextArea.Enable = 'on';
            app.LowerTextArea.Enable = 'on';
            app.CentralTextArea.Enable = 'off';
            app.LeftTextArea.Editable = 'off';
            app.RightTextArea.Editable = 'off';
            app.UpperTextArea.Editable = 'off';
            app.LowerTextArea.Editable = 'off';
            app.CentralTextArea.Editable = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartTrainingSessionButton.Visible = 'on';
            app.StartTrainingSessionButton.Enable = 'on';
            app.UpperLamp.Color = [0.65,0.65,0.65];
            app.LowerLamp.Color = [0.65,0.65,0.65];
            app.LeftLamp.Color = [0.65,0.65,0.65];
            app.RightLamp.Color = [0.65,0.65,0.65];
            app.LeftTextArea.Visible = 'off';
            app.RightTextArea.Visible = 'off';
            app.UpperTextArea.Visible = 'off';
            app.LowerTextArea.Visible = 'off';
            app.CentralTextArea.Visible = 'off';
            app.TextArea.Enable = 'off';
            app.LeftTextArea.Enable = 'off';
            app.RightTextArea.Enable = 'off';
            app.UpperTextArea.Enable = 'off';
            app.LowerTextArea.Enable = 'off';
            app.CentralTextArea.Enable = 'off';
            app.LeftTextArea.Editable = 'off';
            app.RightTextArea.Editable = 'off';
            app.UpperTextArea.Editable = 'off';
            app.LowerTextArea.Editable = 'off';
            app.CentralTextArea.Editable = 'off';
        end

        % Button pushed function: StartTrainingSessionButton
        function StartTrainingSessionButtonPushed(app, event)
            % Callback that controls the starting of the training session

            app.StartTrainingSessionButton.Enable = 'off';
            app.CentralTextArea.Visible = 'on';
            app.CentralTextArea.Enable = 'on';

            % First Countdown inside the central text area
            app.countdownValue = 3;
            app.CentralTextArea.Value = sprintf('Start in %d', app.countdownValue);
            pause(1)
            if ~isvalid(app); return; end 
            app.countdownTimer = timer('ExecutionMode','fixedRate','Period',1,'TimerFcn', @(~,~) app.updateCountdown());
            start(app.countdownTimer);
            pause(3)
            if ~isvalid(app); return; end     
            app.CentralTextArea.Visible = 'off';
            
            % Training session
            caseOfTraining = ["rest","open","close"];
            app.caseOfLamp = ["up","right","down","left"];
            for turn = 1:2
                for numCurrentCase = 1:3
                    app.currentCase = caseOfTraining(numCurrentCase);
                    switch app.currentCase
                        case 'rest'
                            app.CentralTextArea.Visible = 'on';
                            pause(1)
                            if ~isvalid(app); return; end
                            app.restCountdownValue = 60;
                            app.restTimer = timer('ExecutionMode','fixedRate','Period',1,'TimerFcn', @(~,~) app.updateRestCountdown());
                            start(app.restTimer)
                            pause(60)
                            if ~isvalid(app); return; end
                            app.CentralTextArea.Visible = 'off';
                        case 'open'
                            pause(1)
                            if ~isvalid(app); return; end
                            updateLampCountdown(app)
                            if ~isvalid(app); return; end
                        case 'close'
                            pause(1)
                            if ~isvalid(app); return; end
                            updateLampCountdown(app)
                            if ~isvalid(app); return; end
                    end
                end
            end
            if ~isvalid(app); return; end
            app.CentralTextArea.Visible = 'on';
            app.CentralTextArea.Value = sprintf('End of the training session');
            pause(5)
            if ~isvalid(app); return; end
            resetApplication(app)
        end

        % Value changed function: LeftTextArea
        function LeftTextAreaValueChanged(app, event)
            % Left Text Area
        end

        % Value changed function: RightTextArea
        function RightTextAreaValueChanged(app, event)
            % Right Text Area            
        end

        % Value changed function: UpperTextArea
        function UpperTextAreaValueChanged(app, event)
            % Upper Text Area
        end

        % Value changed function: LowerTextArea
        function LowerTextAreaValueChanged(app, event)
            % Lower Text Area
        end

        % Value changed function: CentralTextArea
        function CentralTextAreaValueChanged(app, event)
            % Central Text Area
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop and delete the countdown timer, if it exists and is running
            if ~isempty(app.countdownTimer) && isvalid(app.countdownTimer)
                stop(app.countdownTimer);
                delete(app.countdownTimer);
            end
        
            % Stop and delete the rest timer, if it exists and is running
            if ~isempty(app.restTimer) && isvalid(app.restTimer)
                stop(app.restTimer);
                delete(app.restTimer);
            end
        
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartTrainingSessionButton = uibutton(app.UIFigure, 'push');
            app.StartTrainingSessionButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingSessionButtonPushed, true);
            app.StartTrainingSessionButton.Text = 'Start Training Session';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.UpperLamp = uilamp(app.UIFigure);
            app.LowerLamp = uilamp(app.UIFigure);
            app.LeftLamp = uilamp(app.UIFigure);
            app.RightLamp = uilamp(app.UIFigure);
            app.LeftTextArea = uitextarea(app.UIFigure);
            app.LeftTextArea.ValueChangedFcn = createCallbackFcn(app, @LeftTextAreaValueChanged, true);
            app.LeftTextArea.Value = {'Watch here'};
            app.RightTextArea = uitextarea(app.UIFigure);
            app.RightTextArea.ValueChangedFcn = createCallbackFcn(app, @RightTextAreaValueChanged, true);
            app.RightTextArea.Value = {'Watch here'};
            app.UpperTextArea = uitextarea(app.UIFigure);
            app.UpperTextArea.ValueChangedFcn = createCallbackFcn(app, @UpperTextAreaValueChanged, true);
            app.UpperTextArea.Value = {'Watch here'};
            app.LowerTextArea = uitextarea(app.UIFigure);
            app.LowerTextArea.ValueChangedFcn = createCallbackFcn(app, @LowerTextAreaValueChanged, true);
            app.LowerTextArea.Value = {'Watch here'};
            app.CentralTextArea = uitextarea(app.UIFigure);
            app.CentralTextArea.ValueChangedFcn = createCallbackFcn(app, @CentralTextAreaValueChanged, true);
            app.CentralTextArea.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrainingSession

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end